classdef interface_chooser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioConfigFigure        matlab.ui.Figure
        OutputDeviceDropDown     matlab.ui.control.DropDown
        OutputStepLabel          matlab.ui.control.Label
        OutputDeviceLabel        matlab.ui.control.Label
        OutputTestLabel          matlab.ui.control.Label
        OutputTestLeftButton     matlab.ui.control.Button
        OutputTestRightButton    matlab.ui.control.Button
        RequerimentsLabel        matlab.ui.control.Label
        InputDeviceDropDown      matlab.ui.control.DropDown
        InputStepLabel           matlab.ui.control.Label
        InputDeviceLabel         matlab.ui.control.Label
        InputTestLabel           matlab.ui.control.Label
        InputTestRecordButton    matlab.ui.control.Button
        InputTestPlaybackButton  matlab.ui.control.Button
        LatencyStepLabel         matlab.ui.control.Label
        LatencyLabel             matlab.ui.control.Label
        DoneButton               matlab.ui.control.Button
        ReloadButton             matlab.ui.control.Button
        LatencyTestAxes          matlab.ui.control.UIAxes
        LatencyMeasureButton     matlab.ui.control.Button
        LatencyField             matlab.ui.control.NumericEditField
        LatencyUnitsLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        OutputDevices % Description
        InputDevices % Description
    end
    
    methods (Access = private)
        
        function loadDevices(app)
            info = audiodevinfo;
            app.InputDevices = info.input;
            app.OutputDevices = info.output;
            
            app.InputDeviceDropDown.Items = {info.input.Name};
            app.InputDeviceDropDown.ItemsData = {info.input.ID};
            app.OutputDeviceDropDown.Items = {info.output.Name};
            app.OutputDeviceDropDown.ItemsData = {info.output.ID};
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.loadDevices
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            app.loadDevices
        end

        % Button pushed function: LatencyMeasureButton
        function LatencyMeasureButtonPushed(app, event)
            d = uiprogressdlg(app.AudioConfigFigure, 'Indeterminate', 'off', 'Message', 'Performing loopback latency measure test...', 'Title', 'Loopback test');
            for i = 1:23
              pause(0.1);
              d.Value = i/23;
            end
            pause(0.2);
            close(d);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioConfigFigure = uifigure;
            app.AudioConfigFigure.Name = 'Audio interfaces';
            app.OutputDeviceDropDown = uidropdown(app.AudioConfigFigure);
            app.OutputDeviceDropDown.Tooltip = {'Audio device to use for playback.'};
            app.OutputStepLabel = uilabel(app.AudioConfigFigure);
            app.OutputStepLabel.Text = 'Step 1. Select output device (DAC)';
            app.OutputDeviceLabel = uilabel(app.AudioConfigFigure);
            app.OutputDeviceLabel.Text = 'Device';
            app.OutputTestLabel = uilabel(app.AudioConfigFigure);
            app.OutputTestLabel.Text = 'Test';
            app.OutputTestLeftButton = uibutton(app.AudioConfigFigure, 'push');
            app.OutputTestLeftButton.Icon = 'media-play.png';
            app.OutputTestLeftButton.Tooltip = {'Play 4 seconds of a 440Hz tone on the left channel.'};
            app.OutputTestLeftButton.Text = 'Play left';
            app.OutputTestRightButton = uibutton(app.AudioConfigFigure, 'push');
            app.OutputTestRightButton.Icon = 'media-play.png';
            app.OutputTestRightButton.Tooltip = {'Play 4 seconds of a 440Hz tone on the right channel.'};
            app.OutputTestRightButton.Text = 'Play right';
            app.RequerimentsLabel = uilabel(app.AudioConfigFigure);
            app.RequerimentsLabel.Text = {'For proper app operation please select the audio interfaces to use.'; 'Make sure the selected devices support 16-bit stereo at 48kHz.'};
            app.InputDeviceDropDown = uidropdown(app.AudioConfigFigure);
            app.InputDeviceDropDown.Tooltip = {'Audio device to use for recording.'};
            app.InputStepLabel = uilabel(app.AudioConfigFigure);
            app.InputStepLabel.Text = 'Step 2. Select input device (ADC)';
            app.InputDeviceLabel = uilabel(app.AudioConfigFigure);
            app.InputDeviceLabel.Text = 'Device';
            app.InputTestLabel = uilabel(app.AudioConfigFigure);
            app.InputTestLabel.Text = 'Test';
            app.InputTestRecordButton = uibutton(app.AudioConfigFigure, 'push');
            app.InputTestRecordButton.Icon = 'media-record.png';
            app.InputTestRecordButton.Tooltip = {'Record a four second sample.'};
            app.InputTestRecordButton.Text = 'Record';
            app.InputTestPlaybackButton = uibutton(app.AudioConfigFigure, 'push');
            app.InputTestPlaybackButton.Icon = 'media-play.png';
            app.InputTestPlaybackButton.Enable = 'off';
            app.InputTestPlaybackButton.Tooltip = {'Play the last recorded sample using the configured output device.'};
            app.InputTestPlaybackButton.Text = 'Playback';
            app.LatencyStepLabel = uilabel(app.AudioConfigFigure);
            app.LatencyStepLabel.Text = 'Step 3. Measure loopback latency (delay)';
            app.LatencyLabel = uilabel(app.AudioConfigFigure);
            app.LatencyLabel.Text = 'Latency';
            app.LatencyUnitsLabel = uilabel(app.AudioConfigFigure);
            app.LatencyUnitsLabel.Text = 'samples';
            app.LatencyField = uieditfield(app.AudioConfigFigure, 'numeric');
            app.LatencyField.Limits = [0 Inf];
            app.LatencyField.RoundFractionalValues = 'on';
            app.LatencyField.ValueDisplayFormat = '%11d';
            app.LatencyField.Tooltip = {'Input-to-output latency in samples.'};
            app.LatencyMeasureButton = uibutton(app.AudioConfigFigure, 'push');
            app.LatencyMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @LatencyMeasureButtonPushed, true);
            app.LatencyMeasureButton.Icon = 'audio.png';
            app.LatencyMeasureButton.Tooltip = {'Perform a loopback test to measure latency.'};
            app.LatencyMeasureButton.Text = 'Mesasure';
            app.LatencyTestAxes = uiaxes(app.AudioConfigFigure);
            title(app.LatencyTestAxes, '')
            xlabel(app.LatencyTestAxes, 'time [s]')
            ylabel(app.LatencyTestAxes, '')
            app.LatencyTestAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ReloadButton = uibutton(app.AudioConfigFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Icon = 'reload.png';
            app.ReloadButton.Tooltip = {'Query system for available audio devices.'};
            app.ReloadButton.Text = 'Reload devices';
            app.DoneButton = uibutton(app.AudioConfigFigure, 'push');
            app.DoneButton.Icon = 'check.png';
            app.DoneButton.Enable = 'off';
            app.DoneButton.Tooltip = {'Apply changes.'};
            app.DoneButton.Text = 'Done';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interface_chooser

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioConfigFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioConfigFigure)
        end
    end
end