classdef simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DurationEditFieldLabel        matlab.ui.control.Label
        DurationEditField             matlab.ui.control.NumericEditField
        SimulationparametersLabel     matlab.ui.control.Label
        ModulationfreqEditFieldLabel  matlab.ui.control.Label
        ModulationfreqEditField       matlab.ui.control.NumericEditField
        InputfrequencyEditFieldLabel  matlab.ui.control.Label
        InputfrequencyEditField       matlab.ui.control.NumericEditField
        InputamplitudeEditFieldLabel  matlab.ui.control.Label
        InputamplitudeEditField       matlab.ui.control.NumericEditField
        ModulationamplEditFieldLabel  matlab.ui.control.Label
        ModulationamplEditField       matlab.ui.control.NumericEditField
        RLCinductanceEditFieldLabel   matlab.ui.control.Label
        RLCinductanceEditField        matlab.ui.control.NumericEditField
        RLCcaoacitanceEditFieldLabel  matlab.ui.control.Label
        RLCcaoacitanceEditField       matlab.ui.control.NumericEditField
        RLCresistanceEditFieldLabel   matlab.ui.control.Label
        RLCresistanceEditField        matlab.ui.control.NumericEditField
        ResultsLabel                  matlab.ui.control.Label
        SimulateButton                matlab.ui.control.Button
        UIAxes1                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes7                       matlab.ui.control.UIAxes
        UIAxes9                       matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SimulateButtonPushed;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            dlg = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Message', 'Simulating...');

            %DEFINE PARAMETERS
            %Global
            Fs = 3*100e6; %sampling freq
            Vdd=app.ModulationamplEditField.Value; %vdd
            
            %Test input
            d=app.DurationEditField.Value;
            t = 0:1/Fs:d;
            %L=d*Fs;
            f=app.InputfrequencyEditField.Value;
            %Triangle wave
            A=app.InputamplitudeEditField.Value;
            fw=app.ModulationfreqEditField.Value;
            %Filter
            L=app.RLCinductanceEditField.Value * 1e-6;
            C=app.RLCcaoacitanceEditField.Value * 1e-9;
            R=app.RLCresistanceEditField.Value;
            %==============================================
            %DEFINE TEST SIGNAL
            
            s=A*cos(2*pi*f*t);
            
            
            %==============================================
            %MAIN
            tw = Vdd * Triangle_wave(A,fw,d,Fs); %we create a triangle wave with the same duration of the signal and Fs
            
            a = Vdd * Comparator(s,tw); %Comparator Block - Out=1 if input>wave, else 0 
            
            [fc,out]=Filter(L,C,R,Fs,a);
            %==============================================
            %PLOTING SIGNALS

            input_fft = fft(s) /length(s);
            comp_fft = fft(a) /length(a);
            out_fft = fft(out) /length(out);
            fft_freq = (0:length(s)-1) / length(s) * Fs;
            fft_lim = [80 120e3];
            fft_ylim = [-200 20];

            ax = app.UIAxes1;
            plot(ax, t,s);
            xlim(ax, [0 2.3/f]);
            title(ax, 'Input signal');
            xlabel(ax, 't [s]');

%             ax = app.UIAxes3;
%             semilogx(ax, fft_freq(1:length(s)/2), amplitude_to_db(input_fft(1:length(s)/2)));
%             xlim(ax, fft_lim);
%             ylim(ax, fft_ylim);
%             title(ax, 'FFT of the input');
%             xlabel(ax, 'f [Hz]');
%             ylabel(ax, '[dB]');
            
            ax = app.UIAxes2;
            plot(ax, t,a);
            xlim(ax, [0 20/fw]);
            title(ax, 'Compared signal');
            xlabel(ax, 't [s]');
            
            ax = app.UIAxes3;
            semilogx(ax, fft_freq, amplitude_to_db(comp_fft));
            xlim(ax, fft_lim);
            ylim(ax, fft_ylim);
            title(ax, 'FFT of compared signal');
            xlabel(ax, 'f [Hz]');
            ylabel(ax, '[dB]');
            
            ax = app.UIAxes7;
            plot(ax, t,out,t,s);
            xlim(ax, [10/f 15/f]);  
            title(ax, 'Input signal (orange) and output (blue)');
            xlabel(ax, 't [s]');
            
            ax = app.UIAxes9;
            plot(ax, fft_freq, amplitude_to_db(out_fft), ...
                fft_freq, amplitude_to_db(input_fft));
            xlim(ax, fft_lim);
            %ylim(ax, fft_ylim);
            title(ax, 'FFT Input (orange) and output (blue)');
            xlabel(ax, 'f [Hz]');
            ylabel(ax, '[dB]');
            
            ax = app.UIAxes4;
            Plot_filter(ax, L,C,R,Fs,a);
            hold(ax, "on");
            semilogx(ax, fft_freq(1:length(s)/2), amplitude_to_db(comp_fft(1:length(s)/2)));
            xlim(ax, fft_lim);
            ylim(ax, fft_ylim);
            xlabel(ax, "f [Hz]");
            ylabel(ax, "[dB]");
            title(ax, "RLC filter response (blue) and input (orange)");
            hold(ax, "off");

            close(dlg);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Class-D amplifier simulator | Virtual instruments';
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField.ValueDisplayFormat = '%11.4g s';
            app.DurationEditField.Value = 0.025;
            app.SimulationparametersLabel = uilabel(app.UIFigure);
            app.SimulationparametersLabel.Text = 'Simulation parameters';
            app.ModulationfreqEditFieldLabel = uilabel(app.UIFigure);
            app.ModulationfreqEditFieldLabel.Text = 'Modulation freq.';
            app.ModulationfreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModulationfreqEditField.ValueDisplayFormat = '%11.4g Hz';
            app.ModulationfreqEditField.Value = 5000000;
            app.InputfrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.InputfrequencyEditFieldLabel.Text = 'Input frequency';
            app.InputfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputfrequencyEditField.ValueDisplayFormat = '%11.0f Hz';
            app.InputfrequencyEditField.Value = 10000;
            app.InputamplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.InputamplitudeEditFieldLabel.Text = 'Input amplitude';
            app.InputamplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputamplitudeEditField.ValueDisplayFormat = '%11.4g V';
            app.InputamplitudeEditField.Value = 5;
            app.ModulationamplEditFieldLabel = uilabel(app.UIFigure);
            app.ModulationamplEditFieldLabel.Text = 'Modulation ampl.';
            app.ModulationamplEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModulationamplEditField.ValueDisplayFormat = '%11.4g V';
            app.ModulationamplEditField.Value = 5;
            app.RLCinductanceEditFieldLabel = uilabel(app.UIFigure);
            app.RLCinductanceEditFieldLabel.Text = 'RLC inductance';
            app.RLCinductanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.RLCinductanceEditField.ValueDisplayFormat = '%11.0f µH';
            app.RLCinductanceEditField.Value = 22;
            app.RLCcaoacitanceEditFieldLabel = uilabel(app.UIFigure);
            app.RLCcaoacitanceEditFieldLabel.Text = 'RLC caoacitance';
            app.RLCcaoacitanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.RLCcaoacitanceEditField.ValueDisplayFormat = '%11.0f nF';
            app.RLCcaoacitanceEditField.Value = 470;
            app.RLCresistanceEditFieldLabel = uilabel(app.UIFigure);
            app.RLCresistanceEditFieldLabel.Text = 'RLC resistance';
            app.RLCresistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.RLCresistanceEditField.ValueDisplayFormat = '%11.0f Ω';
            app.RLCresistanceEditField.Value = 8;
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.UIAxes9 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = simulator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end